classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Button       matlab.ui.control.Button
        utensilsT    matlab.ui.control.Button
        hotdogC      matlab.ui.control.Button
        flowersC     matlab.ui.control.Button
        teabagC      matlab.ui.control.Button
        metalcanR    matlab.ui.control.Button
        cardboardR   matlab.ui.control.Button
        chipsT       matlab.ui.control.Button
        STARTButton  matlab.ui.control.Button
        square       matlab.ui.control.Image
        background   matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
          key = event.Key;
                if strcmp(key,'leftarrow')
                    app.utensilsT.Position(1) = app.utensilsT.Position(1) -10
                end
                if strcmp(key,'rightarrow')
                    app.utensilsT.Position(1) = app.utensilsT.Position(1) +10
                end
                if strcmp(key,'uparrow')
                    app.utensilsT.Position(2) = app.utensilsT.Position(2) +10
                end
                if strcmp(key,'downarrow')
                    app.utensilsT.Position(2) = app.utensilsT.Position(2) -10
                end
            end
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            value = app.START.Button;
            if value==1
                %%command to run
            else
                %%command to stop
            end
        end

        % Button pushed function: utensilsT
        function utensilsTButtonPushed(app, event)
            value = app.Button.Value;
            if value == 1;
            key = event.Key;
                if strcmp(key,'leftarrow')
                    app.utensilsT.Position(1) = app.utensilsT.Position(1) -10
                end
                if strcmp(key,'rightarrow')
                    app.utensilsT.Position(1) = app.utensilsT.Position(1) +10
                end
                if strcmp(key,'uparrow')
                    app.utensilsT.Position(2) = app.utensilsT.Position(2) +10
                end
                if strcmp(key,'downarrow')
                    app.utensilsT.Position(2) = app.utensilsT.Position(2) -10
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.background = uiimage(app.UIFigure);
            app.background.ImageSource = fullfile(pathToMLAPP, 'LabelledTrashcan.png');
            app.square = uiimage(app.UIFigure);
            app.square.ImageSource = fullfile(pathToMLAPP, 'square.png');
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.chipsT = uibutton(app.UIFigure, 'push');
            app.chipsT.Icon = fullfile(pathToMLAPP, 'lays chips.png');
            app.chipsT.Text = '';
            app.cardboardR = uibutton(app.UIFigure, 'push');
            app.cardboardR.Icon = fullfile(pathToMLAPP, 'cardboard.png');
            app.cardboardR.Text = '';
            app.metalcanR = uibutton(app.UIFigure, 'push');
            app.metalcanR.Icon = fullfile(pathToMLAPP, 'metal can.png');
            app.metalcanR.Text = '';
            app.teabagC = uibutton(app.UIFigure, 'push');
            app.teabagC.Icon = fullfile(pathToMLAPP, 'tea bag.png');
            app.teabagC.Text = '';
            app.flowersC = uibutton(app.UIFigure, 'push');
            app.flowersC.Icon = fullfile(pathToMLAPP, 'dead flowers.png');
            app.flowersC.Text = '';
            app.hotdogC = uibutton(app.UIFigure, 'push');
            app.hotdogC.Icon = fullfile(pathToMLAPP, 'hotdog.png');
            app.hotdogC.Text = '';
            app.utensilsT = uibutton(app.UIFigure, 'push');
            app.utensilsT.ButtonPushedFcn = createCallbackFcn(app, @utensilsTButtonPushed, true);
            app.utensilsT.Icon = fullfile(pathToMLAPP, 'Utensils.png');
            app.utensilsT.Text = '';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.Icon = fullfile(pathToMLAPP, 'plastic_cup.png');
            app.Button.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end